classdef FinalAppIsoLineVecLablePltNew < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        TabGroup                     matlab.ui.container.TabGroup
        PropertyCalculationTab       matlab.ui.container.Tab
        GridLayout2                  matlab.ui.container.GridLayout
        PropertyNo1DropDownLabel     matlab.ui.control.Label
        PropertyNo1DropDown          matlab.ui.control.DropDown
        PropertyNo2DropDownLabel     matlab.ui.control.Label
        PropertyNo2DropDown          matlab.ui.control.DropDown
        Prop1EditField               matlab.ui.control.NumericEditField
        Prop2EditField               matlab.ui.control.NumericEditField
        UITable                      matlab.ui.control.Table
        UnitProp1DropDown            matlab.ui.control.DropDown
        UnitProp2DropDown            matlab.ui.control.DropDown
        StatesPropertiesLabel        matlab.ui.control.Label
        CalculateButton              matlab.ui.control.Button
        UnitLabel                    matlab.ui.control.Label
        ProcessTab                   matlab.ui.container.Tab
        GridLayout2_2                matlab.ui.container.GridLayout
        ProPropertyNo1DropDown       matlab.ui.control.DropDown
        ProPropertyNo2DropDown       matlab.ui.control.DropDown
        ProProp1EditField            matlab.ui.control.NumericEditField
        ProProp2EditField            matlab.ui.control.NumericEditField
        ProStateUITable              matlab.ui.control.Table
        ProUnitProp1DropDown         matlab.ui.control.DropDown
        ProUnitProp2DropDown         matlab.ui.control.DropDown
        ProUITable                   matlab.ui.control.Table
        TableAStatesListLabel        matlab.ui.control.Label
        AddButton                    matlab.ui.control.Button
        UnitLabel_2                  matlab.ui.control.Label
        ProcessDropDown              matlab.ui.control.DropDown
        ProcessCheckBox              matlab.ui.control.CheckBox
        Label                        matlab.ui.control.Label
        PropertyNo1Label             matlab.ui.control.Label
        StateLabelEditField_2Label   matlab.ui.control.Label
        StateLablel                  matlab.ui.control.EditField
        ProcessDropDown_2Label       matlab.ui.control.Label
        ProcessDropDownNew           matlab.ui.control.DropDown
        ProcessAddButton             matlab.ui.control.Button
        StartingStateDropDownLabel   matlab.ui.control.Label
        StartingStateDropDown        matlab.ui.control.DropDown
        FinalStateDropDownLabel      matlab.ui.control.Label
        FinalStateDropDown           matlab.ui.control.DropDown
        UnknownProcessNameEditField  matlab.ui.control.EditField
        ProcessNameLabel             matlab.ui.control.Label
        RemoveButton                 matlab.ui.control.Button
        ProRemoveButton              matlab.ui.control.Button
        ProcessLabelEditFieldLabel   matlab.ui.control.Label
        ProcessLabelEditField        matlab.ui.control.EditField
        PlotsTab                     matlab.ui.container.Tab
        GridLayout3                  matlab.ui.container.GridLayout
        UIAxes                       matlab.ui.control.UIAxes
        PlotDropDown                 matlab.ui.control.DropDown
        PlotButton                   matlab.ui.control.Button
        IsoLinesCheckBox             matlab.ui.control.CheckBox
        IsoLinesList                 matlab.ui.control.DropDown
        IsoLineValue                 matlab.ui.control.EditField
        ProcessLabelDropDownLabel    matlab.ui.control.Label
        ProcessListBoxLabel          matlab.ui.control.Label
        ProcessListBox               matlab.ui.control.ListBox
        ProcessLabelPlot             matlab.ui.control.DropDown
    end

    
    properties (Access = private)
     
        T
        P
        v
        u
        h
        Table
        StateCounter = 1;
        ProcessCounter = 1;
        TableData % Description
        StateInd
        ProInd

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            
            [app.Table,~] = GenTableNew(); 
            
        end

        % Value changed function: PropertyNo1DropDown
        function PropertyNo1DropDownValueChanged(app, event)

            % First Property DropDown
            
            app.PropertyNo2DropDown.Items = {'Select','Pressure','Temperature','Specific Volume - v','Specific Internal Energy - u','Specific Enthropy - h','x'};
            Prop1 = app.PropertyNo1DropDown.Value;
            app.UnitProp2DropDown.Items = {};

                switch Prop1  
                    case 'Select'
                        app.UnitProp1DropDown.Items = {};
                    
                    case 'Pressure' 
                        app.UnitProp1DropDown.Items = {'bar','kPa','MPa','lbt/in2'};
                        app.PropertyNo2DropDown.Items(2) = [];
                    
                    case 'Temperature'  
                        app.UnitProp1DropDown.Items = {[char(0176), 'C'],[char(0176), 'F'],'K'};
                        app.PropertyNo2DropDown.Items(3) = [];
                        
                    case 'Specific Volume - v'  
                        app.UnitProp1DropDown.Items = {'m3/Kg','ft3/lb','MPa','lbt/in2'};
                        app.PropertyNo2DropDown.Items(4) = [];                                     
                    
                    case 'Specific Internal Energy - u'
                        app.UnitProp1DropDown.Items = {'KJ/Kg','Btu/lb'};
                        app.PropertyNo2DropDown.Items(5) = []; 
                    
                    case 'Specific Enthropy - h'    
                        app.UnitProp1DropDown.Items = {'KJ/Kg','Btu/lb'};
                        app.PropertyNo2DropDown.Items(6) = [];                                     
                    
                    case 'x' 
                        app.UnitProp1DropDown.Items = {'No Unit'};
                        app.PropertyNo2DropDown.Items(7) = []; 
                end


        end

        % Value changed function: PropertyNo2DropDown
        function PropertyNo2DropDownValueChanged(app, event)

                  Prop2 = app.PropertyNo2DropDown.Value;

                switch Prop2 
                    
                    case 'Select'
                        app.UnitProp2DropDown.Items = {};
                    
                    case 'Pressure'
                        app.UnitProp2DropDown.Items = {'bar','kPa','MPa','lbt/in2'};
                    
                    case 'Temperature'
                        app.UnitProp2DropDown.Items = {[char(0176), 'C'],[char(0176), 'F'],'K'};
     
                    case 'Specific Volume - v'  
                        app.UnitProp2DropDown.Items = {'m3/Kg','ft3/lb','MPa','lbt/in2'};                                         
                    
                    case 'Specific Internal Energy - u'
                        app.UnitProp2DropDown.Items = {'KJ/Kg','Btu/lb'};
                             
                    case 'Specific Enthropy - h'    
                        app.UnitProp2DropDown.Items = {'KJ/Kg','Btu/lb'};

                    case 'x' 
                        app.UnitProp2DropDown.Items = {'No Unit'};
                     
                end
        end

        % Value changed function: ProPropertyNo1DropDown
        function ProPropertyNo1DropDownValueChanged(app, event)
            
            % First Property DropDown
            
            app.ProPropertyNo2DropDown.Items = {'Select','Pressure','Temperature','Specific Volume - v','Specific Internal Energy - u','Specific Enthropy - h','x'};
            Prop1 = app.ProPropertyNo1DropDown.Value;
            app.ProUnitProp2DropDown.Items = {};

                switch Prop1
                    
                    case 'Select'
                        app.ProUnitProp1DropDown.Items = {};
                       
                    case 'Pressure'
                        app.ProUnitProp1DropDown.Items = {'bar','kPa','MPa','lbt/in2'};
                        app.ProPropertyNo2DropDown.Items(2) = [];
                    
                    case 'Temperature'  
                        app.ProUnitProp1DropDown.Items = {[char(0176), 'C'],[char(0176), 'F'],'K'};
                        app.ProPropertyNo2DropDown.Items(3) = [];
                        
                    case 'Specific Volume - v'  
                        app.ProUnitProp1DropDown.Items = {'m3/Kg','ft3/lb','MPa','lbt/in2'};
                        app.ProPropertyNo2DropDown.Items(4) = [];                                     
                    
                    case 'Specific Internal Energy - u'
                        app.ProUnitProp1DropDown.Items = {'KJ/Kg','Btu/lb'};
                        app.ProPropertyNo2DropDown.Items(5) = []; 
                    
                    case 'Specific Enthropy - h'    
                        app.ProUnitProp1DropDown.Items = {'KJ/Kg','Btu/lb'};
                        app.ProPropertyNo2DropDown.Items(6) = [];                                     
                    
                    case 'x' 
                        app.ProUnitProp1DropDown.Items = {'No Unit'};
                        app.ProPropertyNo2DropDown.Items(7) = []; 
                end
            
        end

        % Value changed function: ProPropertyNo2DropDown
        function ProPropertyNo2DropDownValueChanged(app, event)
  
            Prop2 = app.ProPropertyNo2DropDown.Value;
            
                        
            switch Prop2
                
                case 'Select'
                    app.ProUnitProp2DropDown.Items = {};
                    
                case 'Pressure'
                    app.ProUnitProp2DropDown.Items = {'bar','kPa','MPa','lbt/in2'};
                    
                case 'Temperature'
                    app.ProUnitProp2DropDown.Items = {[char(0176), 'C'],[char(0176), 'F'],'K'};
                    
                case 'Specific Volume - v'
                    app.ProUnitProp2DropDown.Items = {'m3/Kg','ft3/lb','MPa','lbt/in2'};
                    
                case 'Specific Internal Energy - u'
                    app.ProUnitProp2DropDown.Items = {'KJ/Kg','Btu/lb'};
                    
                case 'Specific Enthropy - h'
                    app.ProUnitProp2DropDown.Items = {'KJ/Kg','Btu/lb'};
                    
                case 'x'
                    app.ProUnitProp2DropDown.Items = {'No Unit'};
                    
            end


        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)

            % Table List
            app.UITable.ColumnName = {'Phase','P [bar]',['T [', char(0176), 'C]'],'v [m3/Kg]','u [KJ/Kg]','h [KJ/Kg]','x'};

      
            % First Property
            Property1 = app.PropertyNo1DropDown.Value;
            Value1 = app.Prop1EditField.Value;
                        
            switch Property1
                
                case 'Pressure'
                    Prop1 = 'P';
                    app.UITable.ColumnName{2}=  ['P [',app.UnitProp1DropDown.Value,']'];
                    
                case 'Temperature'
                    Prop1 = 'T';
                    app.UITable.ColumnName{3}=  ['T [',app.UnitProp1DropDown.Value,']'];

                case 'Specific Volume - v'
                    Prop1 = 'v';
                    app.UITable.ColumnName{4}=  ['v [',app.UnitProp1DropDown.Value,']'];
                case 'Specific Internal Energy - u'
                    Prop1 = 'u';
                    app.UITable.ColumnName{5}=  ['u [',app.UnitProp1DropDown.Value,']'];
                case 'Specific Enthropy - h'
                    Prop1 = 'h';
                    app.UITable.ColumnName{6}=  ['h [',app.UnitProp1DropDown.Value,']'];
                case 'x'
                    Prop1 = 'x';

            end
            
            
            % Second Property
            Property2 = app.PropertyNo2DropDown.Value;
            Value2 = app.Prop2EditField.Value;
                    
            switch Property2
                
                case 'Pressure'
                    Prop2 = 'P';
                    app.UITable.ColumnName{2}=  ['P [',app.UnitProp2DropDown.Value,']'];
                    
                case 'Temperature'
                    Prop2 = 'T';
                     app.UITable.ColumnName{3}=  ['T [',app.UnitProp2DropDown.Value,']'];

                case 'Specific Volume - v'
                    Prop2 = 'v';
                    app.UITable.ColumnName{4}=  ['v [',app.UnitProp2DropDown.Value,']'];
                case 'Specific Internal Energy - u'
                    Prop2 = 'u';
                     app.UITable.ColumnName{5}=  ['u [',app.UnitProp2DropDown.Value,']'];
                case 'Specific Enthropy - h'
                    Prop2 = 'h';
                     app.UITable.ColumnName{6}=  ['h [',app.UnitProp2DropDown.Value,']'];
                case 'x'
                    Prop2 = 'x';
            end
            
            
           % State Identification
           if strcmp(Prop1,'x') || strcmp(Prop2,'x')
           State = 'Saturated';   
           else
           SampleData= StateDetect(Prop1,Value1,Prop2,Value2,app.Table);
           State = SampleData.State;
           end 
     
      
               switch State
               
               case 'Saturated'
                   
                   if strcmp(Prop1,'x') || strcmp(Prop2,'x')   
                       if strcmp(Prop1,'x')
                       SatState = XSaturated(Value1,Prop2,Value2,app.Table);
                       else
                       SatState = XSaturated(Value2,Prop1,Value1,app.Table);    
                       end                
                       PropertyData = {'Saturated',SatState.P,SatState.T,SatState.v,SatState.u,SatState.h,SatState.x};
                   else
                       SatState = StateDetect(Prop1,Value1,Prop2,Value2,app.Table);
                       PropertyData = {'Saturated',SatState.P,SatState.T,SatState.v,SatState.u,SatState.h,SatState.x};
                       
                   end
                   
                case 'SuperHeat'
                   
                    if strcmp(Prop1,'P') || strcmp(Prop2,'P')
                        if strcmp(Prop1,'P')
                            SuperState = SuperHeat(Value1,Prop2,Value2,app.Table);
                        else
                            SuperState = SuperHeat(Value2,Prop1,Value1,app.Table);
                        end
                        PropertyData = {'SuperHeat',SuperState.P,SuperState.T,SuperState.v,SuperState.u,SuperState.h,'NaN'};
                    else
                        SuperState = SuperHeatR(Prop1,Value1,Prop2,Value2,app.Table);
                        PropertyData = {'SuperHeat',SuperState.P,SuperState.T,SuperState.v,SuperState.u,SuperState.h,'NaN'};     
                    end
                                             
                case 'SubCooled'
                    
                    if strcmp(Prop1,'P') || strcmp(Prop2,'P')
                        if strcmp(Prop1,'P')
                            SubState = SubCooled(Value1,Prop2,Value2,app.Table);
                        else
                            SubState = SubCooled(Value2,Prop1,Value1,app.Table);
                        end
                        PropertyData = {'SubCooled',SubState.P,SubState.T,SubState.v,SubState.u,SubState.h,'NaN'};
                    else
                        SubState = SubCooledR(Prop1,Value1,Prop2,Value2,app.Table);
                        PropertyData = {'SubCooled',SubState.P,SubState.T,SubState.v,SubState.u,SubState.h,'NaN'};
                    end
                      
               end
               
               if strcmp(State,'Saturated')
                   
                   if SatState.x == 1
                       PropertyData{1} = 'Saturated Vapor';
                   elseif SatState.x ==0
                       PropertyData{1} = 'Saturated Liquid';
                   else
                       PropertyData{1} = 'Mixure';
                   end
                   
               end
               
               app.UITable.Data = PropertyData;


        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            
        
            % Table List
            app.ProStateUITable.ColumnName = {'No.','Phase','P [bar]',['T [', char(0176), 'C]'],'v [m3/Kg]','u [KJ/Kg]','h [KJ/Kg]','x'};
            
            
            % First Property
            
            if app.ProcessCheckBox.Value
                
                Opt = app.ProcessDropDown.Value;
                
                switch Opt
                    
                    case 'Isobar'
                        Property1 = 'Pressure';
                        Value1 = app.ProStateUITable.Data{end,3};
                        
                    case 'Isotherm'
                        Property1 = 'Temperature';
                        Value1 = app.ProStateUITable.Data{end,4};
                        
                    case 'Constant Volume'
                        Property1 = 'Specific Volume - v';
                        Value1 = app.ProStateUITable.Data{end,5};
                        
                end

                
            else
                
                Property1 = app.ProPropertyNo1DropDown.Value;
                Value1 = app.ProProp1EditField.Value; 
                
            end


            switch Property1
                
                case 'Pressure'
                    Prop1 = 'P';
                    app.ProStateUITable.ColumnName{3}=  ['P [',app.ProUnitProp1DropDown.Value,']'];
                    
                case 'Temperature'
                    Prop1 = 'T';
                    app.ProStateUITable.ColumnName{4}=  ['T [',app.ProUnitProp1DropDown.Value,']'];

                case 'Specific Volume - v'
                    Prop1 = 'v';
                    app.ProStateUITable.ColumnName{5}=  ['v [',app.ProUnitProp1DropDown.Value,']'];
                case 'Specific Internal Energy - u'
                    Prop1 = 'u';
                    app.ProStateUITable.ColumnName{6}=  ['u [',app.ProUnitProp1DropDown.Value,']'];
                case 'Specific Enthropy - h'
                    Prop1 = 'h';
                    app.ProStateUITable.ColumnName{7}=  ['h [',app.ProUnitProp1DropDown.Value,']'];
                case 'x'
                    Prop1 = 'x';

            end

            % Second Property
            Property2 = app.ProPropertyNo2DropDown.Value;
            Value2 = app.ProProp2EditField.Value;
                    
            switch Property2
                
                case 'Pressure'
                    Prop2 = 'P';
                    app.ProStateUITable.ColumnName{3}=  ['P [',app.ProUnitProp2DropDown.Value,']'];
                    
                case 'Temperature'
                    Prop2 = 'T';
                     app.ProStateUITable.ColumnName{4}=  ['T [',app.ProUnitProp2DropDown.Value,']'];

                case 'Specific Volume - v'
                    Prop2 = 'v';
                    app.ProStateUITable.ColumnName{5}=  ['v [',app.ProUnitProp2DropDown.Value,']'];
                case 'Specific Internal Energy - u'
                    Prop2 = 'u';
                     app.ProStateUITable.ColumnName{6}=  ['u [',app.ProUnitProp2DropDown.Value,']'];
                case 'Specific Enthropy - h'
                    Prop2 = 'h';
                     app.ProStateUITable.ColumnName{7}=  ['h [',app.ProUnitProp2DropDown.Value,']'];
                case 'x'
                    Prop2 = 'x';
            end
            
            
           % State Identification
           if strcmp(Prop1,'x') || strcmp(Prop2,'x')
           State = 'Saturated';   
           else
           SampleData= StateDetect(Prop1,Value1,Prop2,Value2,app.Table);
           State = SampleData.State;
           end 
     
             StateLable = app.StateLablel.Value;
             
               switch State
                
               case 'Saturated'
                   
                   if strcmp(Prop1,'x') || strcmp(Prop2,'x')   
                       if strcmp(Prop1,'x')
                       SatState = XSaturated(Value1,Prop2,Value2,app.Table);
                       else
                       SatState = XSaturated(Value2,Prop1,Value1,app.Table);    
                       end                
                       PropertyData = {StateLable,'Saturated',SatState.P,SatState.T,SatState.v,SatState.u,SatState.h,SatState.x};
                   else
                       SatState = StateDetect(Prop1,Value1,Prop2,Value2,app.Table);
                       PropertyData = {StateLable,'Saturated',SatState.P,SatState.T,SatState.v,SatState.u,SatState.h,SatState.x};
                       
                   end
                   
                case 'SuperHeat'
                   
                    if strcmp(Prop1,'P') || strcmp(Prop2,'P')
                        if strcmp(Prop1,'P')
                            SuperState = SuperHeat(Value1,Prop2,Value2,app.Table);
                        else
                            SuperState = SuperHeat(Value2,Prop1,Value1,app.Table);
                        end
                        PropertyData = {StateLable,'SuperHeat',SuperState.P,SuperState.T,SuperState.v,SuperState.u,SuperState.h,'NaN'};
                    else
                        SuperState = SuperHeatR(Prop1,Value1,Prop2,Value2,app.Table);
                        PropertyData = {StateLable,'SuperHeat',SuperState.P,SuperState.T,SuperState.v,SuperState.u,SuperState.h,'NaN'};     
                    end
                                             
                case 'SubCooled'
                    
                    if strcmp(Prop1,'P') || strcmp(Prop2,'P')
                        if strcmp(Prop1,'P')
                            SubState = SubCooled(Value1,Prop2,Value2,app.Table);
                        else
                            SubState = SubCooled(Value2,Prop1,Value1,app.Table);
                        end
                        PropertyData = {StateLable,'SubCooled',SubState.P,SubState.T,SubState.v,SubState.u,SubState.h,'NaN'};
                    else
                        SubState = SubCooledR(Prop1,Value1,Prop2,Value2,app.Table);
                        PropertyData = {StateLable,'SubCooled',SubState.P,SubState.T,SubState.v,SubState.u,SubState.h,'NaN'};
                    end
                      
               end
               
               if strcmp(State,'Saturated')
                   
                   if SatState.x == 1
                       PropertyData{2} = 'Saturated Vapor';
                   elseif SatState.x ==0
                       PropertyData{2} = 'Saturated Liquid';
                   else
                       PropertyData{2} = 'Mixure';
                   end
                   
               end
               
               
               IsUpdate = false;
               
               if isempty(app.ProStateUITable.Data)
                   app.ProStateUITable.Data = [app.ProStateUITable.Data;PropertyData];
               else
                   for i = 1:size(app.ProStateUITable.Data,1)
                       if strcmp(StateLable,app.ProStateUITable.Data{i,1})
                           app.ProStateUITable.Data(i,:) = PropertyData;
                           IsUpdate = true;
                           break
                       end
                   end
                   
                   if ~IsUpdate  
                       app.ProStateUITable.Data = [app.ProStateUITable.Data;PropertyData];                
                   end
                   
               end
               
               
               app.StartingStateDropDown.Items = {'Select',app.ProStateUITable.Data{:,1}};
               
%                if app.ProcessCheckBox.Value
%                    ProcessData = {app.ProcessDropDown.Value,[num2str(app.ProStateUITable.Data{end-1,1}), ' to ',num2str(app.ProStateUITable.Data{end,1}) ]};  
%                else
%                ProcessData = {};
%                end
%                
%                app.ProUITable.Data =  [app.ProUITable.Data; ProcessData];


            StartingList = app.ProStateUITable.Data(:,1);
            app.StartingStateDropDown.Items = [{'Select'},StartingList(:)'];


        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            cla(app.UIAxes,'reset')
            
            % Process Data
            
            ProcLabel = app.ProcessLabelPlot.Value;
            
            if  ~strcmp(ProcLabel,'Select') 
                
                for i=1:size(app.ProUITable.Data,1)
                    if strcmp(ProcLabel,app.ProUITable.Data{i,1})   
                        Start = app.ProUITable.Data{i,2};
                        Final = app.ProUITable.Data{i,3};
                        ProcType = app.ProUITable.Data{i,end};
                        break
                    end
                end
                
                % Starting State
                for i=1:size(app.ProStateUITable.Data,1)
                    if strcmp(Start,app.ProStateUITable.Data{i,1})
                        vStart = app.ProStateUITable.Data{i,5};
                        PStart = app.ProStateUITable.Data{i,3};
                        TStart = app.ProStateUITable.Data{i,4};
                        break
                    end
                end
                
                % Starting State
                for i=1:size(app.ProStateUITable.Data,1)
                    if strcmp(Final,app.ProStateUITable.Data{i,1})
                        vFinal = app.ProStateUITable.Data{i,5};
                        PFinal = app.ProStateUITable.Data{i,3};
                        TFinal = app.ProStateUITable.Data{i,4};
                        break
                    end
                end
                
                
                switch ProcType
                    
                    case 'Isobar'
                        ProcData = ProcIsoBarLine(PStart,vStart,vFinal,app.Table);
                    case 'Isotherm'
                        ProcData = ProcIsoBarLine(TStart,vStart,vFinal,app.Table);
                    case 'Unknown'
                        ProcData.v = [vStart,vFinal];
                        ProcData.T = [TStart,TFinal];
                        ProcData.P = [PStart,PFinal];
                end

            end


            Opt = app.PlotDropDown.Value;

            if app.IsoLinesCheckBox.Value
                
                IsoValue = str2double(strsplit(app.IsoLineValue.Value,','));
                OptIso = app.IsoLinesList.Value;
                
                for i = 1:numel(IsoValue)
                    
                    switch OptIso
                        
                        case 'IsoBar'
                            Out = IsoBarLineNew(IsoValue(i),app.Table);
                            hold(app.UIAxes,'on')
                            switch Opt
                                
                                case 'V - T'
                                    plot(Out.v,Out.T,'-r','Parent',app.UIAxes);
                                case 'V - P'
                                    plot(Out.v,Out.P,'-r','Parent',app.UIAxes);
                            end
                            
                        case 'IsoTherm'
                            Out = IsoThermLine(IsoValue(i),app.Table);
                            hold(app.UIAxes,'on')
                            
                            switch Opt
                                
                                case 'V - T'
                                    plot(Out.v,Out.T,'-r','Parent',app.UIAxes);
                                case 'V - P'
                                    plot(Out.v,Out.P,'-r','Parent',app.UIAxes);
                            end
                    end
                end
            end


            switch Opt
                
                
                case 'V - T'
     
                    plot(app.Table.Sat.vf,app.Table.Sat.T,'-b',app.Table.Sat.vg,app.Table.Sat.T,'-b','Parent',app.UIAxes);

                    title(app.UIAxes,'V-T')
                    xlabel(app.UIAxes,'V')
                    ylabel(app.UIAxes,'T')
                    hold(app.UIAxes,'on')
                    
                     % Process Line
                     if strcmp(ProcType,'Unknown')
                         plot(ProcData.v,ProcData.T,'.:k','Parent',app.UIAxes);
                     else
                         plot(ProcData.v,ProcData.T,'-k','Parent',app.UIAxes);
                     end
%                      plot(ProcData.v,ProcData.T,'-g','Parent',app.UIAxes);
                    
                    for i = 1:size(app.ProStateUITable.Data,1)
                        
                        hold(app.UIAxes,'on')
                        plot(app.UIAxes,app.ProStateUITable.Data{i,5},app.ProStateUITable.Data{i,4},'Ok','MarkerFaceColor','k')
                        text(app.UIAxes,app.ProStateUITable.Data{i,5},app.ProStateUITable.Data{i,4},['  ' app.ProStateUITable.Data{i,1}])
                    
                    end
                 
                case 'V - P'
                    
                    plot(app.Table.Sat.vf,app.Table.Sat.P,'-b',app.Table.Sat.vg,app.Table.Sat.P,'-b','Parent',app.UIAxes);
                    title(app.UIAxes,'V-P')
                    xlabel(app.UIAxes,'V')
                    ylabel(app.UIAxes,'P')
                     hold(app.UIAxes,'on')
                     
                     % Process Line
                     if strcmp(ProcType,'Unknown')
                         plot(ProcData.v,ProcData.P,':k','Parent',app.UIAxes);
                     else
                         plot(ProcData.v,ProcData.P,'-k','Parent',app.UIAxes);
                     end
                    
                    for i = 1:size(app.ProStateUITable.Data,1)
                        
                        hold(app.UIAxes,'on')
                        plot(app.UIAxes,app.ProStateUITable.Data{i,5},app.ProStateUITable.Data{i,3},'Ok','MarkerFaceColor','k')
                        text(app.UIAxes,app.ProStateUITable.Data{i,5},app.ProStateUITable.Data{i,3},['  ' app.ProStateUITable.Data{i,1}])
                        
                    end
                    
                case 'Select'
                    
                    cla(app.UIAxes,'reset')

            end

            set(app.UIAxes,'XScale','log')
                

        end

        % Value changed function: ProcessCheckBox
        function ProcessCheckBoxValueChanged(app, event)
          
            ProcessCheck = app.ProcessCheckBox.Value;
            
            switch  ProcessCheck
                
                
                case true
                    app.ProPropertyNo1DropDown.Enable = 0;
                    app.ProProp1EditField.Enable = 0;
                    app.ProUnitProp1DropDown.Enable = 0;
                    app.ProcessDropDown.Enable = 1;
                    app.PropertyNo1Label.Visible = 0;
                    app.Label.Text = 'Property';
                    
                    
                case false
                    app.ProPropertyNo1DropDown.Enable = 1;
                    app.ProProp1EditField.Enable = 1;
                    app.ProUnitProp1DropDown.Enable = 1;
                    app.ProcessDropDown.Enable = 0;
                    app.PropertyNo1Label.Visible = 1;
                    app.Label.Text = 'Property No.2';
                    
            end
            
            
        end

        % Value changed function: ProcessDropDown
        function ProcessDropDownValueChanged(app, event)
             ProcessCheck = app.ProcessCheckBox.Value;
            
            
             % First Property DropDown
             
             app.ProPropertyNo2DropDown.Items = {'Select','Pressure','Temperature','Specific Volume - v','Specific Internal Energy - u','Specific Enthropy - h','x'};
             Prop = app.ProcessDropDown.Value;
             
             
             if ProcessCheck

                 switch Prop
                     
                     case 'Select'
                         app.ProUnitProp1DropDown.Items = {};
                         
                     case 'Isobar'
                         app.ProPropertyNo2DropDown.Items(2) = [];
                         
                     case 'Isotherm'
                         app.ProPropertyNo2DropDown.Items(3) = [];
                         
                     case 'Constant Volume'
                         app.ProPropertyNo2DropDown.Items(4) = [];
                         
                     case 'Specific Internal Energy - u'
                         app.ProPropertyNo2DropDown.Items(5) = [];
                         
                     case 'Specific Enthropy - h'
                         app.ProPropertyNo2DropDown.Items(6) = [];
                         
                     case 'x'
                         app.ProPropertyNo2DropDown.Items(7) = [];
                 end
                 
             end


        end

        % Value changed function: IsoLinesCheckBox
        function IsoLinesCheckBoxValueChanged(app, event)
            Flag = app.IsoLinesCheckBox.Value;
            
            if Flag
                app.IsoLinesList.Enable = 1;
                app.IsoLineValue.Enable = 1;
            else
                app.IsoLinesList.Enable = 0;
                app.IsoLineValue.Enable = 0;
            end
            
        end

        % Cell selection callback: ProStateUITable
        function ProStateUITableCellSelection(app, event)
            app.StateInd = event.Indices;
          
                app.StateLablel.Value = app.ProStateUITable.Data{app.StateInd(1),1};

            
        end

        % Cell selection callback: ProUITable
        function ProUITableCellSelection(app, event)
            app.ProInd = event.Indices;
            app.ProcessLabelEditField.Value = app.ProUITable.Data{app.ProInd(1),1};

            app.StartingStateDropDown.Value = app.ProUITable.Data{app.ProInd(1),2};
            
            app.FinalStateDropDown.Items = app.StartingStateDropDown.Items;
            for i = 2:size(app.ProStateUITable.Data,1)    
                if strcmp(app.FinalStateDropDown.Items{i},app.StartingStateDropDown.Value)
                    app.FinalStateDropDown.Items(i)=[];
                    break
                end
            end
                app.FinalStateDropDown.Value= app.ProUITable.Data{app.ProInd(1),3};
                
            
        end

        % Value changed function: StartingStateDropDown
        function StartingStateDropDownValueChanged(app, event)

            app.FinalStateDropDown.Items = app.StartingStateDropDown.Items;
            
            for i = 2:size(app.ProStateUITable.Data,1)
                
                if strcmp(app.FinalStateDropDown.Items{i},app.StartingStateDropDown.Value)
                    app.FinalStateDropDown.Items(i)=[];
                    break
                end
                
            end

            
        end

        % Button pushed function: ProcessAddButton
        function ProcessAddButtonPushed(app, event)


            IsUpdate = false;
            
            I = zeros(1,size(app.ProUITable.Data,1));
            for i = 1:size(app.ProUITable.Data,1)
                if strcmp(app.StartingStateDropDown.Value,app.ProUITable.Data{i,2})
                    I(i) = i;
                end    
            end
            I(I==0) = [];
            for j = 1:numel(I)
                
                if strcmp(app.FinalStateDropDown.Value,app.ProUITable.Data{I(j),3})
                    
                    I_Index = I(j);
                    IsUpdate = true;
                    break
                end
            end
            
                
            if ~strcmp(app.ProcessDropDownNew.Value,'Select')
%                 ProcessName = app.UnknownProcessNameEditField.Value;
%             else
                ProcessName = app.ProcessDropDownNew.Value;
            end
            
            PropertyData = {app.ProcessLabelEditField.Value,app.StartingStateDropDown.Value,app.FinalStateDropDown.Value,ProcessName};
            
            if IsUpdate
                app.ProUITable.Data(I_Index,:) = PropertyData;
            else
                app.ProUITable.Data = [app.ProUITable.Data;PropertyData];
            end
                

            % Make Process List 
            app.ProcessLabelPlot.Items = {'Select',app.ProUITable.Data{:,1}};
            
            % Update ListBox
            app.ProcessListBox.Items = app.ProUITable.Data(:,1);


        end

        % Value changed function: ProcessDropDownNew
        function ProcessDropDownNewValueChanged(app, event)
            value = app.ProcessDropDownNew.Value;
            
            if strcmp(value,'Unknown')
                app.UnknownProcessNameEditField.Enable = 1;
                app.ProcessNameLabel.Enable = 1;
                
            else
                app.UnknownProcessNameEditField.Enable = 0;
                app.ProcessNameLabel.Enable = 0;
            end
            
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)


           for i  = 1:size(app.ProUITable.Data,1)             
               if strcmp(app.ProUITable.Data{i,2},app.ProStateUITable.Data{app.StateInd(1),1}) || strcmp(app.ProUITable.Data{i,3},app.ProStateUITable.Data{app.StateInd(1),1})        
                   app.ProUITable.Data(i,:)=[];
               end
           end
           
           app.ProStateUITable.Data(app.StateInd(1),:)=[];
           app.StateLablel.Value = '';
           
           StartingList = app.ProStateUITable.Data(:,1);
           app.StartingStateDropDown.Items = [{'Select'},StartingList(:)'];
           
           
            % Update ListBox
            app.ProcessListBox.Items = app.ProUITable.Data(:,1);
           
    
        end

        % Button pushed function: ProRemoveButton
        function ProRemoveButtonPushed(app, event)
            
            app.ProUITable.Data(app.ProInd(1),:)=[];
            
            % Update ListBox
            app.ProcessListBox.Items = app.ProUITable.Data(:,1);
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.PropertyCalculationTab = uitab(app.TabGroup);
            app.PropertyCalculationTab.Title = 'Property Calculation';
            app.GridLayout2 = uigridlayout(app.PropertyCalculationTab);
            app.PropertyNo1DropDownLabel = uilabel(app.GridLayout2);
            app.PropertyNo1DropDownLabel.Text = 'Property No.1';
            app.PropertyNo1DropDown = uidropdown(app.GridLayout2);
            app.PropertyNo1DropDown.Items = {'Select', 'Pressure', 'Temperature', 'Specific Volume - v', 'Specific Internal Energy - u', 'Specific Enthropy - h', 'x'};
            app.PropertyNo1DropDown.ValueChangedFcn = createCallbackFcn(app, @PropertyNo1DropDownValueChanged, true);
            app.PropertyNo1DropDown.Value = 'Select';
            app.PropertyNo2DropDownLabel = uilabel(app.GridLayout2);
            app.PropertyNo2DropDownLabel.Text = 'Property No.2';
            app.PropertyNo2DropDown = uidropdown(app.GridLayout2);
            app.PropertyNo2DropDown.Items = {'Select', 'Pressure', 'Temperature', 'Sprecifiv Volume - v', 'Specific Internal Energy - u', 'Specific Enthropy - h', 'x'};
            app.PropertyNo2DropDown.ValueChangedFcn = createCallbackFcn(app, @PropertyNo2DropDownValueChanged, true);
            app.PropertyNo2DropDown.Value = 'Select';
            app.Prop1EditField = uieditfield(app.GridLayout2, 'numeric');
            app.Prop2EditField = uieditfield(app.GridLayout2, 'numeric');
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Phase'; 'P'; 'T'; 'v'; 'u'; 'h'; 'x'};
            app.UITable.RowName = {};
            app.UnitProp1DropDown = uidropdown(app.GridLayout2);
            app.UnitProp1DropDown.Items = {};
            app.UnitProp1DropDown.Value = {};
            app.UnitProp2DropDown = uidropdown(app.GridLayout2);
            app.UnitProp2DropDown.Items = {};
            app.UnitProp2DropDown.Value = {};
            app.StatesPropertiesLabel = uilabel(app.GridLayout2);
            app.StatesPropertiesLabel.Text = ' States Properties';
            app.CalculateButton = uibutton(app.GridLayout2, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.UnitLabel = uilabel(app.GridLayout2);
            app.UnitLabel.Text = 'Unit';
            app.ProcessTab = uitab(app.TabGroup);
            app.ProcessTab.Title = 'Process';
            app.GridLayout2_2 = uigridlayout(app.ProcessTab);
            app.ProPropertyNo1DropDown = uidropdown(app.GridLayout2_2);
            app.ProPropertyNo1DropDown.Items = {'Select', 'Pressure', 'Temperature', 'Specific Volume - v', 'Specific Internal Energy - u', 'Specific Enthropy - h', 'x'};
            app.ProPropertyNo1DropDown.ValueChangedFcn = createCallbackFcn(app, @ProPropertyNo1DropDownValueChanged, true);
            app.ProPropertyNo1DropDown.Value = 'Select';
            app.ProPropertyNo2DropDown = uidropdown(app.GridLayout2_2);
            app.ProPropertyNo2DropDown.Items = {'Select', 'Pressure', 'Temperature', 'Sprecifiv Volume - v', 'Specific Internal Energy - u', 'Specific Enthropy - h', 'x'};
            app.ProPropertyNo2DropDown.ValueChangedFcn = createCallbackFcn(app, @ProPropertyNo2DropDownValueChanged, true);
            app.ProPropertyNo2DropDown.Value = 'Select';
            app.ProProp1EditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.ProProp2EditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.ProStateUITable = uitable(app.GridLayout2_2);
            app.ProStateUITable.ColumnName = {'State Label'; 'Phase'; 'P'; 'T'; 'v'; 'u'; 'h'; 'x'};
            app.ProStateUITable.RowName = {};
            app.ProStateUITable.CellSelectionCallback = createCallbackFcn(app, @ProStateUITableCellSelection, true);
            app.ProUnitProp1DropDown = uidropdown(app.GridLayout2_2);
            app.ProUnitProp1DropDown.Items = {};
            app.ProUnitProp1DropDown.Value = {};
            app.ProUnitProp2DropDown = uidropdown(app.GridLayout2_2);
            app.ProUnitProp2DropDown.Items = {};
            app.ProUnitProp2DropDown.Value = {};
            app.ProUITable = uitable(app.GridLayout2_2);
            app.ProUITable.ColumnName = {'Process Label'; 'Start State'; 'Final State'; 'Process Type'};
            app.ProUITable.RowName = {};
            app.ProUITable.CellSelectionCallback = createCallbackFcn(app, @ProUITableCellSelection, true);
            app.TableAStatesListLabel = uilabel(app.GridLayout2_2);
            app.TableAStatesListLabel.Text = ' Table A - States List';
            app.AddButton = uibutton(app.GridLayout2_2, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add/Update';
            app.UnitLabel_2 = uilabel(app.GridLayout2_2);
            app.UnitLabel_2.Text = 'Unit';
            app.ProcessDropDown = uidropdown(app.GridLayout2_2);
            app.ProcessDropDown.Items = {'Select', 'Isobar', 'Isotherm', 'Constant Volume'};
            app.ProcessDropDown.ValueChangedFcn = createCallbackFcn(app, @ProcessDropDownValueChanged, true);
            app.ProcessDropDown.Enable = 'off';
            app.ProcessDropDown.Value = 'Select';
            app.ProcessCheckBox = uicheckbox(app.GridLayout2_2);
            app.ProcessCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProcessCheckBoxValueChanged, true);
            app.ProcessCheckBox.Enable = 'off';
            app.ProcessCheckBox.Text = ' Process';
            app.Label = uilabel(app.GridLayout2_2);
            app.Label.Text = 'Property No.2';
            app.PropertyNo1Label = uilabel(app.GridLayout2_2);
            app.PropertyNo1Label.Text = 'Property No.1';
            app.StateLabelEditField_2Label = uilabel(app.GridLayout2_2);
            app.StateLabelEditField_2Label.Text = 'State Label';
            app.StateLablel = uieditfield(app.GridLayout2_2, 'text');
            app.ProcessDropDown_2Label = uilabel(app.GridLayout2_2);
            app.ProcessDropDown_2Label.Text = 'Process';
            app.ProcessDropDownNew = uidropdown(app.GridLayout2_2);
            app.ProcessDropDownNew.Items = {'Select', 'Isobar', 'Isotherm', 'Constant Volume', 'Isentropic', 'Unknown'};
            app.ProcessDropDownNew.ValueChangedFcn = createCallbackFcn(app, @ProcessDropDownNewValueChanged, true);
            app.ProcessDropDownNew.Value = 'Select';
            app.ProcessAddButton = uibutton(app.GridLayout2_2, 'push');
            app.ProcessAddButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessAddButtonPushed, true);
            app.ProcessAddButton.Text = 'Add/Update';
            app.StartingStateDropDownLabel = uilabel(app.GridLayout2_2);
            app.StartingStateDropDownLabel.Text = 'Starting State';
            app.StartingStateDropDown = uidropdown(app.GridLayout2_2);
            app.StartingStateDropDown.Items = {'Select', '', 'Option 2', 'Option 3', 'Option 4'};
            app.StartingStateDropDown.ValueChangedFcn = createCallbackFcn(app, @StartingStateDropDownValueChanged, true);
            app.StartingStateDropDown.Value = 'Select';
            app.FinalStateDropDownLabel = uilabel(app.GridLayout2_2);
            app.FinalStateDropDownLabel.Text = 'Final State';
            app.FinalStateDropDown = uidropdown(app.GridLayout2_2);
            app.FinalStateDropDown.Items = {'Select', 'Option 2', 'Option 3', 'Option 4'};
            app.FinalStateDropDown.Value = 'Select';
            app.UnknownProcessNameEditField = uieditfield(app.GridLayout2_2, 'text');
            app.UnknownProcessNameEditField.Enable = 'off';
            app.ProcessNameLabel = uilabel(app.GridLayout2_2);
            app.ProcessNameLabel.Enable = 'off';
            app.ProcessNameLabel.Text = 'Process Name';
            app.RemoveButton = uibutton(app.GridLayout2_2, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.ProRemoveButton = uibutton(app.GridLayout2_2, 'push');
            app.ProRemoveButton.ButtonPushedFcn = createCallbackFcn(app, @ProRemoveButtonPushed, true);
            app.ProRemoveButton.Text = 'Remove';
            app.ProcessLabelEditFieldLabel = uilabel(app.GridLayout2_2);
            app.ProcessLabelEditFieldLabel.Text = 'Process Label';
            app.ProcessLabelEditField = uieditfield(app.GridLayout2_2, 'text');
            app.PlotsTab = uitab(app.TabGroup);
            app.PlotsTab.Title = 'Plots';
            app.GridLayout3 = uigridlayout(app.PlotsTab);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PlotDropDown = uidropdown(app.GridLayout3);
            app.PlotDropDown.Items = {'Select', 'V - P', 'V - T'};
            app.PlotDropDown.Value = 'Select';
            app.PlotButton = uibutton(app.GridLayout3, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.IsoLinesCheckBox = uicheckbox(app.GridLayout3);
            app.IsoLinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @IsoLinesCheckBoxValueChanged, true);
            app.IsoLinesCheckBox.Text = ' Iso Lines';
            app.IsoLinesCheckBox.Value = true;
            app.IsoLinesList = uidropdown(app.GridLayout3);
            app.IsoLinesList.Items = {'Select', 'IsoBar', 'IsoTherm'};
            app.IsoLinesList.Value = 'Select';
            app.IsoLineValue = uieditfield(app.GridLayout3, 'text');
            app.ProcessLabelDropDownLabel = uilabel(app.GridLayout3);
            app.ProcessLabelDropDownLabel.Text = 'Process Label';
            app.ProcessLabelPlot = uidropdown(app.GridLayout3);
            app.ProcessLabelPlot.Items = {'Select', 'Process 1 ', 'Process 2', ''};
            app.ProcessLabelPlot.Value = 'Select';
            app.ProcessListBoxLabel = uilabel(app.GridLayout3);
            app.ProcessListBoxLabel.Text = 'Process';
            app.ProcessListBox = uilistbox(app.GridLayout3);
            app.ProcessListBox.Items = {};
            app.ProcessListBox.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalAppIsoLineVecLablePltNew

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end