classdef Masoud < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        PropertyNo1ButtonGroup         matlab.ui.container.ButtonGroup
        TemperatureTButton             matlab.ui.control.RadioButton
        PressurePButton                matlab.ui.control.RadioButton
        PropertyNo2ButtonGroup         matlab.ui.container.ButtonGroup
        TemperatureTButton_2           matlab.ui.control.RadioButton
        PressurePButton_2              matlab.ui.control.RadioButton
        SpecificVolumevButton          matlab.ui.control.RadioButton
        SpecificInternalEnergyuButton  matlab.ui.control.RadioButton
        SpecificEnthropyhButton        matlab.ui.control.RadioButton
        PropertyNo1DropDownLabel       matlab.ui.control.Label
        PropertyNo1DropDown            matlab.ui.control.DropDown
        PropertyNo2DropDownLabel       matlab.ui.control.Label
        PropertyNo2DropDown            matlab.ui.control.DropDown
        Prop1EditField                 matlab.ui.control.NumericEditField
        Prop2EditField                 matlab.ui.control.NumericEditField
        Button                         matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
    end

    
    properties (Access = private)
        T
        P
        v
        u
        h% Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: Prop2EditField, PropertyNo1ButtonGroup
        function PropertyNo1ButtonGroupSelectionChanged(app, event)
%             Prop1 = app.PropertyNo1ButtonGroup.SelectedObject.Text;
%             
%             switch Prop1          
%                 case ' Pressure - P'            
%                     app.PressurePButton_2.Enable = 0;
%                     app.TemperatureTButton_2.Enable= 1;
%                 case ' Temperature - T'                   
%                     app.PressurePButton_2.Enable = 1;
%                     app.TemperatureTButton_2.Enable= 0;                           
%             end
%             
            
        end

        % Value changed function: Prop1EditField, 
        % PropertyNo1DropDown
        function PropertyNo1DropDownValueChanged(app, event)
            Prop1 = app.PropertyNo1DropDown.Value;
            
            switch Prop1
                case 'Select..'
                    app.PropertyNo2DropDown.Items = {}; 
                case 'Temperature' 
                    app.T = app.Prop1EditField.Value; 
                    app.PropertyNo2DropDown.Items = {'Pressure','Specific Volume - v','Specific Internal Energy - u','Specific Enthropy - h'};                  
                case 'Pressure'
                    app.P = app.Prop1EditField.Value; 
                    app.PropertyNo2DropDown.Items = {'Temperature','Specific Volume - v','Specific Internal Energy - u','Specific Enthropy - h'};                           
            end
            
                Prop2 = app.PropertyNo2DropDown.Value;
                switch Prop2
          
                case 'Temperature' 
                    app.T = app.Prop1EditField.Value; 
                case 'Pressure'
                    app.P = app.Prop1EditField.Value; 
                case 'Specific Volume - v' 
                    app.v = app.Prop1EditField.Value; 
                case 'Specific Internal Energy - u'
                    app.u = app.Prop1EditField.Value; 
                case 'Specific Enthropy - h'
                    app.h = app.Prop1EditField.Value;
               
                end
       
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
           
            if isempty(app.T)
                app.T = 0;
            end
            if isempty(app.P)
                app.P = 0;
            end
            if isempty(app.v)
                app.v = 0;
            end
            if isempty(app.u)
                app.u = 0;
            end
            if isempty(app.h)
                app.h = 0;
            end
            app.UITable.Data = {app.T,app.P,app.v,app.u,app.h};
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.PropertyNo1ButtonGroup = uibuttongroup(app.GridLayout);
            app.PropertyNo1ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PropertyNo1ButtonGroupSelectionChanged, true);
            app.PropertyNo1ButtonGroup.Title = 'Property No. 1';
            app.TemperatureTButton = uiradiobutton(app.PropertyNo1ButtonGroup);
            app.TemperatureTButton.Text = ' Temperature - T';
            app.TemperatureTButton.Value = true;
            app.PressurePButton = uiradiobutton(app.PropertyNo1ButtonGroup);
            app.PressurePButton.Text = ' Pressure - P';
            app.PropertyNo2ButtonGroup = uibuttongroup(app.GridLayout);
            app.PropertyNo2ButtonGroup.Title = 'Property No. 2';
            app.TemperatureTButton_2 = uiradiobutton(app.PropertyNo2ButtonGroup);
            app.TemperatureTButton_2.Text = ' Temperature - T';
            app.TemperatureTButton_2.Value = true;
            app.PressurePButton_2 = uiradiobutton(app.PropertyNo2ButtonGroup);
            app.PressurePButton_2.Text = ' Pressure - P';
            app.SpecificVolumevButton = uiradiobutton(app.PropertyNo2ButtonGroup);
            app.SpecificVolumevButton.Text = ' Specific Volume - v';
            app.SpecificInternalEnergyuButton = uiradiobutton(app.PropertyNo2ButtonGroup);
            app.SpecificInternalEnergyuButton.Text = ' Specific Internal Energy - u';
            app.SpecificEnthropyhButton = uiradiobutton(app.PropertyNo2ButtonGroup);
            app.SpecificEnthropyhButton.Text = ' Specific Enthropy- h';
            app.PropertyNo1DropDownLabel = uilabel(app.GridLayout);
            app.PropertyNo1DropDownLabel.Text = 'Property No. 1';
            app.PropertyNo1DropDown = uidropdown(app.GridLayout);
            app.PropertyNo1DropDown.Items = {'Select..', 'Pressure', 'Temperature'};
            app.PropertyNo1DropDown.ValueChangedFcn = createCallbackFcn(app, @PropertyNo1DropDownValueChanged, true);
            app.PropertyNo1DropDown.Value = 'Select..';
            app.PropertyNo2DropDownLabel = uilabel(app.GridLayout);
            app.PropertyNo2DropDownLabel.Text = 'Property No. 2';
            app.PropertyNo2DropDown = uidropdown(app.GridLayout);
            app.PropertyNo2DropDown.Items = {};
            app.PropertyNo2DropDown.Value = {};
            app.Prop1EditField = uieditfield(app.GridLayout, 'numeric');
            app.Prop1EditField.ValueChangedFcn = createCallbackFcn(app, @PropertyNo1DropDownValueChanged, true);
            app.Prop2EditField = uieditfield(app.GridLayout, 'numeric');
            app.Prop2EditField.ValueChangedFcn = createCallbackFcn(app, @PropertyNo1ButtonGroupSelectionChanged, true);
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'T'; 'P'; 'v'; 'u'; 'h'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Masoud

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end