classdef MasoudNewStates < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        PropertyNo1DropDownLabel  matlab.ui.control.Label
        PropertyNo1DropDown       matlab.ui.control.DropDown
        PropertyNo2DropDownLabel  matlab.ui.control.Label
        PropertyNo2DropDown       matlab.ui.control.DropDown
        Prop1EditField            matlab.ui.control.NumericEditField
        Prop2EditField            matlab.ui.control.NumericEditField
        SolveButton               matlab.ui.control.Button
        UITable                   matlab.ui.control.Table
        StateLabelEditFieldLabel  matlab.ui.control.Label
        StateLabelEditField       matlab.ui.control.EditField
    end

    
    properties (Access = private)
        T
        P
        v
        u
        h % Description
        Table
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Prop1EditField, 
        % PropertyNo1DropDown
        function PropertyNo1DropDownValueChanged(app, event)
            
            app.PropertyNo2DropDown.Items = {'Select..','Pressure','Temperature','Specific Volume - v','Specific Internal Energy - u','Specific Enthropy - h'};
            Prop1 = app.PropertyNo1DropDown.Value;
            
            if strcmp(Prop1,'Select..')
               app.Prop1EditField.Enable = 0;
               app.Prop2EditField.Enable = 0;  
            else
                app.Prop1EditField.Enable = 1;
                switch Prop1        
                    case 'Temperature'                       
                        app.PropertyNo2DropDown.Items(3) = [];                                     
                    case 'Pressure' 
                        app.PropertyNo2DropDown.Items(2) = [];           
                end
            end

            
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
           
            [app.Table,~] = GenTableNew();
            
            app.UITable.Data = [];
            app.UITable.Visible = 1;
            Property1 = app.PropertyNo1DropDown.Value;
            Value1 = app.Prop1EditField.Value;
                        
            switch Property1
                
                case 'Temperature'
                    Prop1 = 'T';
                case 'Pressure'
                    Prop1 = 'P';

            end
            
            Property2 = app.PropertyNo2DropDown.Value;
            Value2 = app.Prop2EditField.Value;
                    
            switch Property2
                
                case 'Temperature'
                    Prop2 = 'T';
                case 'Pressure'
                    Prop2 = 'P';
                case 'Specific Volume - v'
                    Prop2 = 'v';
                case 'Specific Internal Energy - u'
                    Prop2 = 'u';
                case 'Specific Enthropy - h'
                    Prop2 = 'h';

            end
            
           Out = StateDetect(Prop1,Value1,Prop2,Value2,app.Table);
           
           State = Out.State;
           
           switch State
               
               case 'Saturated'
                   
                   if strcmp(Prop2,'T') || strcmp(Prop2,'P')        
                   app.UITable.ColumnName = {'State','P','T','vf','vg','uf','ug','hf','hg'};
                   app.UITable.Data = {Out.State,Out.P,Out.T,Out.vf,Out.vg,Out.uf,Out.ug,Out.hf,Out.hg};     
                   else
                   app.UITable.ColumnName = {'State','P','T','v','u','h','x'};
                   app.UITable.Data = {Out.State,Out.P,Out.T,Out.v,Out.u,Out.h,Out.x};  
                   end
                   
               case 'SuperHeat'
                   
                  switch Prop1
                      
                      case 'P'
                          Out = SuperHeat(Value1,Prop2,Value2,app.Table);
                          app.UITable.ColumnName = {'State','P','T','v','u','h'};
                          app.UITable.Data = {'SuperHeat',Out.P,Out.T,Out.v,Out.u,Out.h};  
 
                      case 'T'
                          Out = SuperHeatT(Value1,Prop2,Value2,app.Table);
                          app.UITable.ColumnName = {'State','P','T','v','u','h'};
                          app.UITable.Data = {'SuperHeat',Out.P,Out.T,Out.v,Out.u,Out.h};  
                  end
                   
                   
               case 'SubCooled'
                   
                  switch Prop1 
                      
                  case 'P'
                          Out = SubCooled(Value1,Prop2,Value2,app.Table);
                          app.UITable.ColumnName = {'State','P','T','v','u','h'};
                          app.UITable.Data = {'SubCooled',Out.P,Out.T,Out.v,Out.u,Out.h};  
 
                  case 'T'
                          Out = SubCooledT(Value1,Prop2,Value2,app.Table);
                          app.UITable.ColumnName = {'State','P','T','v','u','h'};
                          app.UITable.Data = {'SubCooled',Out.P,Out.T,Out.v,Out.u,Out.h};  
                  end
      
           end
            

%             app.UITable.Data = {app.T,app.P,app.v,app.u,app.h};
            
        end

        % Value changed function: PropertyNo2DropDown
        function PropertyNo2DropDownValueChanged(app, event)
            Prop2 = app.PropertyNo2DropDown.Value;
                  
                if strcmp(Prop2,'Select..')
                  app.Prop2EditField.Enable = 0; 
                  
                else
                    app.Prop2EditField.Enable = 1;
            
                end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.PropertyNo1DropDownLabel = uilabel(app.GridLayout);
            app.PropertyNo1DropDownLabel.Text = 'Property No. 1';
            app.PropertyNo1DropDown = uidropdown(app.GridLayout);
            app.PropertyNo1DropDown.Items = {'Select..', 'Pressure', 'Temperature'};
            app.PropertyNo1DropDown.ValueChangedFcn = createCallbackFcn(app, @PropertyNo1DropDownValueChanged, true);
            app.PropertyNo1DropDown.Value = 'Select..';
            app.PropertyNo2DropDownLabel = uilabel(app.GridLayout);
            app.PropertyNo2DropDownLabel.Text = 'Property No. 2';
            app.PropertyNo2DropDown = uidropdown(app.GridLayout);
            app.PropertyNo2DropDown.Items = {'Select..', 'Pressure', 'Temperature', 'Sprecifiv Volume - v', 'Specific Internal Energy - u', 'Specific Enthropy - h'};
            app.PropertyNo2DropDown.ValueChangedFcn = createCallbackFcn(app, @PropertyNo2DropDownValueChanged, true);
            app.PropertyNo2DropDown.Value = 'Select..';
            app.Prop1EditField = uieditfield(app.GridLayout, 'numeric');
            app.Prop1EditField.ValueChangedFcn = createCallbackFcn(app, @PropertyNo1DropDownValueChanged, true);
            app.Prop1EditField.Enable = 'off';
            app.Prop2EditField = uieditfield(app.GridLayout, 'numeric');
            app.Prop2EditField.Enable = 'off';
            app.SolveButton = uibutton(app.GridLayout, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'T'; 'P'; 'v'; 'u'; 'h'};
            app.UITable.RowName = {};
            app.StateLabelEditFieldLabel = uilabel(app.GridLayout);
            app.StateLabelEditFieldLabel.Text = 'State Label';
            app.StateLabelEditField = uieditfield(app.GridLayout, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MasoudNewStates

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end