classdef PlotMasoud < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        PressureUnitButtonGroup     matlab.ui.container.ButtonGroup
        barButton                   matlab.ui.control.RadioButton
        kPaButton                   matlab.ui.control.RadioButton
        TempUnitButtonGroup         matlab.ui.container.ButtonGroup
        CButton                     matlab.ui.control.RadioButton
        KelvinButton                matlab.ui.control.RadioButton
        SpecificVolUnitButtonGroup  matlab.ui.container.ButtonGroup
        m3kgButton                  matlab.ui.control.RadioButton
        ft3lbButton                 matlab.ui.control.RadioButton
        UIAxes                      matlab.ui.control.UIAxes
        PlotButton                  matlab.ui.control.StateButton
        PressureConstCheckBox       matlab.ui.control.CheckBox
        TempConstCheckBox           matlab.ui.control.CheckBox
        PressureEditField           matlab.ui.control.NumericEditField
        TemperatureEditField        matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        
%             N = 40;
%             Pressure = linspace(0,6,N);
%             Temp = linspace(0,6,N);
    Fun

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PlotButton
        function PlotButtonValueChanged(app, event)
    
            cla(app.UIAxes,'reset')
            N = 40;
            v = linspace(0,6,N);
            Temp = linspace(0,6,N);
            app.Fun = @(V,T) 10*sin(V).*cos(T);
            
            [V,T] = meshgrid(v,Temp);
            P = app.Fun(T,V);
            
            % Pressure Units
            OptP = app.PressureUnitButtonGroup.SelectedObject.Text;
            switch OptP                   
                case 'kPa'
                    P = P * 100;  
            end
            
            
            % Temperature Units
            OptT = app.TempUnitButtonGroup.SelectedObject.Text;
            switch OptT                   
                case 'Kelvin'
                    T = T + 273;  
            end
            
            
            % Specifiv Vol Units
            OptV = app.SpecificVolUnitButtonGroup.SelectedObject.Text;
            switch OptV                   
                case 'ft3/lb'
                    V = V * 16.0185;  
            end
           
  
%             surf(P,T,V,'Parent',app.UIAxes)
%             surf(P,T,V,'FaceColor',[ 0.8118,0.9020,0.9255],'EdgeColor','none','Parent',app.UIAxes)
            surf(T,V,P,'FaceColor',[0.6078,0.7882,0.8784],'EdgeColor','none','Parent',app.UIAxes)

            grid(app.UIAxes,'on')
            title(app.UIAxes,'P-V-T diagram')
            xlabel(app.UIAxes,'T')
            ylabel(app.UIAxes,'v')
            zlabel(app.UIAxes,'P')
            
            
            % Constant Temperature
            
            if app.TempConstCheckBox.Value == 1
                
                Tconst = app.TemperatureEditField.Value;
                
                PTconst = app.Fun(Tconst*ones(size(v)),v);
                
                hold(app.UIAxes,'on')
                plot3(Tconst*ones(size(v)),v,PTconst,'-.k','parent',app.UIAxes)
                
            end
            
            
           % Constant Pressure
            
            if app.PressureConstCheckBox.Value == 1
                
                Tconst = app.PressureEditField.Value;    
                
                hold(app.UIAxes,'on')
                contour3(T,V,P,[Tconst,Tconst],'-k','parent',app.UIAxes)
                
            end


        end

        % Value changed function: PressureConstCheckBox
        function PressureConstCheckBoxValueChanged(app, event)
            valueP = app.PressureConstCheckBox.Value;
            
            switch valueP
                case 1
                    app.PressureEditField.Enable = 1;
                case 0
                    app.PressureEditField.Enable = 0;
            end
            
        end

        % Value changed function: TempConstCheckBox
        function TempConstCheckBoxValueChanged(app, event)
            valueT = app.TempConstCheckBox.Value;
                  
            switch valueT
                case 1
                    app.TemperatureEditField.Enable = 1;
                case 0
                    app.TemperatureEditField.Enable = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.PressureUnitButtonGroup = uibuttongroup(app.GridLayout);
            app.PressureUnitButtonGroup.Title = 'Pressure Unit';
            app.barButton = uiradiobutton(app.PressureUnitButtonGroup);
            app.barButton.Text = 'bar';
            app.barButton.Value = true;
            app.kPaButton = uiradiobutton(app.PressureUnitButtonGroup);
            app.kPaButton.Text = 'kPa';
            app.TempUnitButtonGroup = uibuttongroup(app.GridLayout);
            app.TempUnitButtonGroup.Title = 'Temp Unit';
            app.CButton = uiradiobutton(app.TempUnitButtonGroup);
            app.CButton.Text = 'C';
            app.CButton.Value = true;
            app.KelvinButton = uiradiobutton(app.TempUnitButtonGroup);
            app.KelvinButton.Text = 'Kelvin';
            app.SpecificVolUnitButtonGroup = uibuttongroup(app.GridLayout);
            app.SpecificVolUnitButtonGroup.Title = 'Specific Vol Unit';
            app.m3kgButton = uiradiobutton(app.SpecificVolUnitButtonGroup);
            app.m3kgButton.Text = 'm3/kg';
            app.m3kgButton.Value = true;
            app.ft3lbButton = uiradiobutton(app.SpecificVolUnitButtonGroup);
            app.ft3lbButton.Text = 'ft3/lb';
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PlotButton = uibutton(app.GridLayout, 'state');
            app.PlotButton.ValueChangedFcn = createCallbackFcn(app, @PlotButtonValueChanged, true);
            app.PlotButton.Text = 'Plot';
            app.PressureConstCheckBox = uicheckbox(app.GridLayout);
            app.PressureConstCheckBox.ValueChangedFcn = createCallbackFcn(app, @PressureConstCheckBoxValueChanged, true);
            app.PressureConstCheckBox.Text = 'Pressure Const.';
            app.PressureConstCheckBox.Value = true;
            app.TempConstCheckBox = uicheckbox(app.GridLayout);
            app.TempConstCheckBox.ValueChangedFcn = createCallbackFcn(app, @TempConstCheckBoxValueChanged, true);
            app.TempConstCheckBox.Text = 'Temp Const.';
            app.TempConstCheckBox.Value = true;
            app.PressureEditField = uieditfield(app.GridLayout, 'numeric');
            app.TemperatureEditField = uieditfield(app.GridLayout, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlotMasoud

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end