classdef PlotMasoud2D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        UIAxes             matlab.ui.control.UIAxes
        PlotButton         matlab.ui.control.StateButton
        ButtonGroup        matlab.ui.container.ButtonGroup
        TempButton         matlab.ui.control.RadioButton
        PressureButton     matlab.ui.control.RadioButton
        UIAxes2            matlab.ui.control.UIAxes
        EditField          matlab.ui.control.NumericEditField
        vScaleButtonGroup  matlab.ui.container.ButtonGroup
        LogButton          matlab.ui.control.RadioButton
        LinearButton       matlab.ui.control.RadioButton
    end

    
    properties (Access = private)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PlotButton
        function PlotButtonValueChanged(app, event)
            cla(app.UIAxes,'reset')
            cla(app.UIAxes2,'reset')
            
            Opt = app.ButtonGroup.SelectedObject.Text;
            
            [Table,~] = GenTableNew();
           
            
        switch Opt
            
            
            case 'Temp'
                
                Out = IsoThermLine(app.EditField.Value,Table);
                
                plot(Table.Sat.vf,Table.Sat.T,'-b',Table.Sat.vg,Table.Sat.T,'-b',Out.v,Out.T,'.-r','Parent',app.UIAxes);
%                 set(app.UIAxes,'XScale','log')
                title(app.UIAxes,'V-T')
                xlabel(app.UIAxes,'V')
                ylabel(app.UIAxes,'T')
                
                
                plot(Table.Sat.vf,Table.Sat.P,'-b',Table.Sat.vg,Table.Sat.P,'-b',Out.v,Out.P,'.-r','Parent',app.UIAxes2)
%                 set(app.UIAxes2,'XScale','log')
                title(app.UIAxes2,'V-P')
                xlabel(app.UIAxes2,'V')
                ylabel(app.UIAxes2,'P')
                
            case 'Pressure'
               Out = IsoBarLine(app.EditField.Value,Table);
                
                plot(Table.Sat.vf,Table.Sat.T,'-b',Table.Sat.vg,Table.Sat.T,'-b',Out.v,Out.T,'.-r','Parent',app.UIAxes);
%                 set(app.UIAxes,'XScale','log')
                title(app.UIAxes,'V-T')
                xlabel(app.UIAxes,'V')
                ylabel(app.UIAxes,'T')
                
                plot(Table.Sat.vf,Table.Sat.P,'-b',Table.Sat.vg,Table.Sat.P,'-b',Out.v,Out.P,'.-r','Parent',app.UIAxes2)
%                 set(app.UIAxes2,'XScale','log')
                title(app.UIAxes2,'V-P')
                xlabel(app.UIAxes2,'V')
                ylabel(app.UIAxes2,'P')
                
        end
            
        if strcmp(app.vScaleButtonGroup.SelectedObject.Text,'Log')
            set(app.UIAxes,'XScale','log')
            set(app.UIAxes2,'XScale','log')
        end
                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PlotButton = uibutton(app.GridLayout, 'state');
            app.PlotButton.ValueChangedFcn = createCallbackFcn(app, @PlotButtonValueChanged, true);
            app.PlotButton.Text = 'Plot';
            app.ButtonGroup = uibuttongroup(app.GridLayout);
            app.ButtonGroup.Title = 'Button Group';
            app.TempButton = uiradiobutton(app.ButtonGroup);
            app.TempButton.Text = 'Temp';
            app.TempButton.Value = true;
            app.PressureButton = uiradiobutton(app.ButtonGroup);
            app.PressureButton.Text = 'Pressure';
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.EditField = uieditfield(app.GridLayout, 'numeric');
            app.vScaleButtonGroup = uibuttongroup(app.GridLayout);
            app.vScaleButtonGroup.Title = 'v Scale';
            app.LogButton = uiradiobutton(app.vScaleButtonGroup);
            app.LogButton.Text = 'Log';
            app.LogButton.Value = true;
            app.LinearButton = uiradiobutton(app.vScaleButtonGroup);
            app.LinearButton.Text = 'Linear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlotMasoud2D

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end