classdef StatesAppPlot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        RemoveButton              matlab.ui.control.Button
        PropertyNo1DropDownLabel  matlab.ui.control.Label
        PropertyNo1DropDown       matlab.ui.control.DropDown
        PropertyNo2DropDownLabel  matlab.ui.control.Label
        PropertyNo2DropDown       matlab.ui.control.DropDown
        Prop1EditField            matlab.ui.control.NumericEditField
        Prop2EditField            matlab.ui.control.NumericEditField
        UITable                   matlab.ui.control.Table
        UIAxes                    matlab.ui.control.UIAxes
        PlotButton                matlab.ui.control.Button
        UnitsDropDownLabel        matlab.ui.control.Label
        UnitsDropDown             matlab.ui.control.DropDown
        Unit2DropDownLabel        matlab.ui.control.Label
        Unit2DropDown             matlab.ui.control.DropDown
        StateNoEditFieldLabel     matlab.ui.control.Label
        StateNoEditField          matlab.ui.control.NumericEditField
        ProcessDropDownLabel      matlab.ui.control.Label
        ProcessDropDown           matlab.ui.control.DropDown
        NewStateButton            matlab.ui.control.Button
        DropDownLabel             matlab.ui.control.Label
        DropDown                  matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        
        T
        P
        v
        u
        h 
        Table  
        StateNumber
        TableData
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PropertyNo1DropDown
        function PropertyNo1DropDownValueChanged(app, event)
                     
            app.PropertyNo2DropDown.Items = {'Pressure','Temperature','Specific Volume - v','Specific Internal Energy - u','Specific Enthropy - h','x'};
            Prop1 = app.PropertyNo1DropDown.Value;

                switch Prop1  
                    case 'Pressure' 
                        app.PropertyNo2DropDown.Items(1) = [];
                    case 'Temperature'                       
                        app.PropertyNo2DropDown.Items(2) = [];                                     
                    case 'Specific Volume - v'                       
                        app.PropertyNo2DropDown.Items(3) = [];                                     
                    case 'Specific Internal Energy - u'
                        app.PropertyNo2DropDown.Items(4) = []; 
                    case 'Specific Enthropy - h'                     
                        app.PropertyNo2DropDown.Items(5) = [];                                     
                    case 'x' 
                        app.PropertyNo2DropDown.Items(6) = []; 
                end


        end

        % Callback function
        function AddButtonPushed(app, event)
            
            [app.Table,~] = GenTableNew(); 
        
            % Table Columns
%             app.UITable.ColumnName = {'No.','State','P','T','v','u','h','x'};
            
            % First Property
            Property1 = app.PropertyNo1DropDown.Value;
            Value1 = app.Prop1EditField.Value;
                        
            switch Property1
                
                case 'Temperature'
                    Prop1 = 'T';
                case 'Pressure'
                    Prop1 = 'P';
                case 'Specific Volume - v'
                    Prop1 = 'v';
                case 'Specific Internal Energy - u'
                    Prop1 = 'u';
                case 'Specific Enthropy - h'
                    Prop1 = 'h';
                case 'x'
                    Prop1 = 'x';

            end
            
            
            % Second Property
            Property2 = app.PropertyNo2DropDown.Value;
            Value2 = app.Prop2EditField.Value;
                    
            switch Property2
                
                case 'Temperature'
                    Prop2 = 'T';
                case 'Pressure'
                    Prop2 = 'P';
                case 'Specific Volume - v'
                    Prop2 = 'v';
                case 'Specific Internal Energy - u'
                    Prop2 = 'u';
                case 'Specific Enthropy - h'
                    Prop2 = 'h';
                case 'x'
                    Prop2 = 'x';
            end
            
            
           % State Identification
           if strcmp(Prop1,'x') || strcmp(Prop2,'x')
           State = 'Saturated';   
           else
           SampleData= StateDetect(Prop1,Value1,Prop2,Value2,app.Table);
           State = SampleData.State;
           end 
           
      
               switch State
               
               case 'Saturated'
                   
                   if strcmp(Prop1,'x') || strcmp(Prop2,'x')   
                       if strcmp(Prop1,'x')
                       SatState = XSaturated(Value1,Prop2,Value2,app.Table);
                       else
                       SatState = XSaturated(Value2,Prop1,Value1,app.Table);    
                       end                
                       PropertyData = {size(app.UITable.Data,1)+1,'Saturated',SatState.P,SatState.T,SatState.v,SatState.u,SatState.h,SatState.x};
                   else
                       SatState = StateDetect(Prop1,Value1,Prop2,Value2,app.Table);
                       PropertyData = {size(app.UITable.Data,1)+1,'Saturated',SatState.P,SatState.T,SatState.v,SatState.u,SatState.h,SatState.x};
                       
                   end
                   
                case 'SuperHeat'
                   
                    if strcmp(Prop1,'P') || strcmp(Prop2,'P')
                        if strcmp(Prop1,'P')
                            SuperState = SuperHeat(Value1,Prop2,Value2,app.Table);
                        else
                            SuperState = SuperHeat(Value2,Prop1,Value1,app.Table);
                        end
                        PropertyData = {size(app.UITable.Data,1)+1,'SuperHeat',SuperState.P,SuperState.T,SuperState.v,SuperState.u,SuperState.h,'NaN'};
                    else
                        SuperState = SuperHeatR(Prop1,Value1,Prop2,Value2,app.Table);
                        PropertyData = {size(app.UITable.Data,1)+1,'SuperHeat',SuperState.P,SuperState.T,SuperState.v,SuperState.u,SuperState.h,'NaN'};     
                    end
                                       
                               
                case 'SubCooled'
                    
                    if strcmp(Prop1,'P') || strcmp(Prop2,'P')
                        if strcmp(Prop1,'P')
                            SubState = SubCooled(Value1,Prop2,Value2,app.Table);
                        else
                            SubState = SubCooled(Value2,Prop1,Value1,app.Table);
                        end
                        PropertyData = {size(app.UITable.Data,1)+1,'SubCooled',SubState.P,SubState.T,SubState.v,SubState.u,SubState.h,'NaN'};
                    else
                        SubState = SubCooledR(Prop1,Value1,Prop2,Value2,app.Table);
                        PropertyData = {size(app.UITable.Data,1)+1,'SubCooled',SubState.P,SubState.T,SubState.v,SubState.u,SubState.h,'NaN'};
                    end
                      
               end
               
               app.UITable.Data = [app.UITable.Data ;PropertyData];


        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            
                [Table1,~] = GenTableNew(); 
                plot(Table1.Sat.vf,Table1.Sat.T,'-b',Table1.Sat.vg,Table1.Sat.T,'-b','Parent',app.UIAxes);
                set(app.UIAxes,'XScale','log')
                title(app.UIAxes,'V-T')
                xlabel(app.UIAxes,'V')
                ylabel(app.UIAxes,'T')


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.RemoveButton = uibutton(app.UIFigure, 'push');
            app.RemoveButton.Text = 'Remove';
            app.PropertyNo1DropDownLabel = uilabel(app.UIFigure);
            app.PropertyNo1DropDownLabel.Text = 'Property No. 1';
            app.PropertyNo1DropDown = uidropdown(app.UIFigure);
            app.PropertyNo1DropDown.Items = {'Pressure', 'Temperature', 'Sprecifiv Volume - v', 'Specific Internal Energy - u', 'Specific Enthropy - h', 'x'};
            app.PropertyNo1DropDown.ValueChangedFcn = createCallbackFcn(app, @PropertyNo1DropDownValueChanged, true);
            app.PropertyNo1DropDown.Value = 'Pressure';
            app.PropertyNo2DropDownLabel = uilabel(app.UIFigure);
            app.PropertyNo2DropDownLabel.Text = 'Property No. 2';
            app.PropertyNo2DropDown = uidropdown(app.UIFigure);
            app.PropertyNo2DropDown.Items = {'Pressure', 'Temperature', 'Sprecifiv Volume - v', 'Specific Internal Energy - u', 'Specific Enthropy - h', 'x'};
            app.PropertyNo2DropDown.Value = 'Temperature';
            app.Prop1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Prop2EditField = uieditfield(app.UIFigure, 'numeric');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'No.'; 'State'; 'P'; 'T'; 'v'; 'u'; 'h'; 'x'};
            app.UITable.RowName = {};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.UnitsDropDownLabel = uilabel(app.UIFigure);
            app.UnitsDropDownLabel.Text = 'Units';
            app.UnitsDropDown = uidropdown(app.UIFigure);
            app.Unit2DropDownLabel = uilabel(app.UIFigure);
            app.Unit2DropDownLabel.Text = 'Unit2';
            app.Unit2DropDown = uidropdown(app.UIFigure);
            app.StateNoEditFieldLabel = uilabel(app.UIFigure);
            app.StateNoEditFieldLabel.Text = 'State No.';
            app.StateNoEditField = uieditfield(app.UIFigure, 'numeric');
            app.ProcessDropDownLabel = uilabel(app.UIFigure);
            app.ProcessDropDownLabel.Text = 'Process';
            app.ProcessDropDown = uidropdown(app.UIFigure);
            app.NewStateButton = uibutton(app.UIFigure, 'push');
            app.NewStateButton.Text = 'New State';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StatesAppPlot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end